classdef SeaCliffs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ControlsPanel                  matlab.ui.container.Panel
        StopButton                     matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        InitializeButton               matlab.ui.control.Button
        HelpButton                     matlab.ui.control.Button
        SetConditionsPanel             matlab.ui.container.Panel
        WaveheightmSpinnerLabel        matlab.ui.control.Label
        waveHeight                     matlab.ui.control.Spinner
        SealevelmSpinnerLabel          matlab.ui.control.Label
        seaLevel                       matlab.ui.control.Spinner
        PrecipitationinyrSpinnerLabel  matlab.ui.control.Label
        precipLevel                    matlab.ui.control.Spinner
        SeaCliffErosionLabel           matlab.ui.control.Label
        profileAxes                    matlab.ui.control.UIAxes
        waterAxes                      matlab.ui.control.UIAxes
        retreatAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x           % cross-shore array (m)
        dx          % cross-shore resolution (m)        
        z           % elevation array (m)
        dz          % elevation resolution (m)
        max_z;
        min_z;
        t           % model run time (yrs)
        dt          % timestep (yrs)
        wl          % water level (m)
        
        cliff_base  % index of cliff base
        cliff_top   % index of cliff top
        zero_index
        
        precip      % precipiation (in/yr)
        Hs          % wave height
        sea_level   % (m)
        sed_vol     % (m^3)
        comp_strength
        tens_strength
        shear_strength
    
        stopped
    end     
    
    methods (Access = private)
        
        function updatePositionVectors(app, cliffPos)                        
            % set values for points vectors
            for i = (1:1:length(app.x))
                if i < app.cliff_base
                    app.x(i) = (i-app.zero_index)*app.dx;
                    app.z(i) = 0.18*(sign(app.x(i) - cliffPos).*abs(app.x(i) - cliffPos).^(2/3));
                elseif i  <= app.cliff_top
                    app.x(i) = cliffPos;
                    app.z(i) = (i - app.cliff_base) * app.dz;
                else
                    app.x(i) = (i-(app.max_z/app.dz)-app.zero_index)*app.dx;
                    app.z(i) = app.max_z;
                end
            end
        end
        
        function updateProfilePlot(app, Hb_depth)            
            ax = app.profileAxes;
            cla(ax)
            plot(ax, app.x, app.z, 'k-', 'linewidth', 2);
            plot(ax, app.x, app.wl, 'b-', 'linewidth', 2);
            patch(ax, [ fliplr(app.x(1:app.cliff_base)) app.x(1:app.cliff_base) app.x(app.cliff_base)],...
                [fliplr(app.wl(1:app.cliff_base)) app.z(1:app.cliff_base) app.wl(app.cliff_base)], ...
                [0 0.5 1], 'facealpha', 0.3);
            patch(ax, [ app.x app.x(end) app.x(1) app.x(1)],...
                [app.z app.min_z app.min_z app.z(1)], ...
                [0.851 0.3294 0.102], 'facealpha', 0.3);    
            
                
            i = find(abs(app.z)+ app.sea_level < Hb_depth, 1);
            if i > 1
                plot(ax, app.x(i), app.wl(i), 'ro', 'markerfacecolor', 'r');
            end
            
            text(ax, app.x(floor(length(app.x)/5)), app.max_z - 4*app.dz, ['t=', num2str(app.t), ' (yrs)']);
            drawnow
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % init run
            app.stopped = 1;
            app.InitializeButtonPushed();
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            % set buttons
            app.RunButton.Enable = 'on';
            app.InitializeButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.stopped = 1;
            
            % initialize user input
            app.precip = 50;
            app.sed_vol = 50;
            app.comp_strength = 5.0 * 10^6;
            app.tens_strength = 60 * 10^3;
            app.shear_strength = 90 * 10^3;            
            % initialize user input
            app.waveHeightValueChanged();
            app.seaLevelValueChanged();            
            
            % set range
            app.t = 0;
            app.dt = 1;
            app.dx = 1;
            app.dz = .5;
            min_x = -100;
            max_x = 50;
            app.min_z = -5;
            app.max_z = 8;            
            num_cliff_face_points = app.max_z/app.dz;
            
            % set initial cliff position
            app.cliff_base = abs(floor(min_x/app.dx));
            app.zero_index = app.cliff_base;
            app.cliff_top = app.cliff_base + num_cliff_face_points;
            
            % intialize point vectors
            app.x = nan(1, (max_x-min_x)/app.dx + num_cliff_face_points);
            app.z = app.x;
            
            
            app.updatePositionVectors(0);
                            
            Hb = 0.39*(9.81^(1/5))*(10*app.Hs^2)^(2/5);
            Hb_depth = Hb/0.7;
                            
            A = Hb*ones(size(app.x));
            for i = (1:1:length(A))
                A(i) = Hb*exp(-(Hb_depth-(app.sea_level + abs(app.z(i)))))^2;
                if A(i) > Hb
                    A(i) = Hb;
                end
            end
            
            app.wl = (A/2) .* sin((2*pi)/(10*sqrt(9.81*Hb_depth)).*(app.x) + (-app.t*pi/16)) + app.sea_level;
            app.wl(app.cliff_base+1:end) = nan;
            
            % intialize profile plot
            ax = app.profileAxes;
            xlabel(ax, 'cross-shore dist. (m)', 'fontsize', 16)
            xlim(ax, [app.x(1) app.x(end)]);
            ylabel(ax, 'elevation (m)', 'fontsize', 16);
            ylim(ax, [app.min_z, app.max_z+2]);
            box(ax, 'on');
            grid(ax, 'on');
            hold(ax, 'on');
            set(ax, 'fontsize', 16);
            
            app.updateProfilePlot(Hb_depth);
            
            % initialize wave and sea level plot
            
            % initialize retreat plots
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % set buttons
            app.RunButton.Enable = 'off';
            app.InitializeButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            app.stopped = 0;
            
            % get A
            A = 200; % arbitray for now
            % get B
            B = 1; % constant rock strength (for now)
            % get notch height
            z_eff = 2;
            
            % get notch erosion
            rho  = 1025;
            g = 9.81;
            kappa = 0.4;
            
            gamma = log(A/B);
            gamma_cliff = 18. * 10^3;
            
            while ~ app.stopped
                H = 1.0 * randn() + app.Hs;
                if H < 0
                    H = 0;
                end
                H_crit = (app.comp_strength/(rho*g))*exp(-gamma);
                
                if H > H_crit
                    max_notch = app.dt * kappa * (gamma + log(rho * g * H / app.comp_strength));
                else
                    max_notch = 0;
                end
                
                % get vertical erosion
                for i = app.cliff_base:app.cliff_top
                    notch = (exp(-(abs(app.z(i)-app.sea_level)/z_eff)^2)) * max_notch;
                    app.x(i) = app.x(i) + notch;
                end
                
                % debris: TODO
                
                % failure critera
                failed = 0;
                [max_notch, max_notch_ind] = max(app.x(app.cliff_base:app.cliff_top));
                max_notch_ind = max_notch_ind + app.cliff_base - 1;
                cliff_face_pos = min(app.x(app.cliff_base:app.cliff_top));
                N = app.x(max_notch_ind) - cliff_face_pos;
                
                % stop criteria
                if app.x(max_notch_ind) >= app.x(end)
                    break;
                end

                total_vol = 0;
                for i = (max_notch_ind:app.cliff_top)
                    if app.x(i) == app.x(app.cliff_top)
                        break;
                    end
                    total_vol = total_vol + 0.5*(app.z(app.cliff_top) - app.z(i)) * ...
                        (app.x(i) - app.x(i+1)); % calculate piecewise volumes of material to be used to find weighted average of overhang height
                end
                H_overhang = total_vol / (app.x(max_notch_ind)-cliff_face_pos); % average cantilever overhang height
                sigma_max = 3 * N^2 * gamma_cliff / H_overhang; % maximum tensile strength (Young and Ashford, 2008)
                tau_max = (3/2) * N * gamma_cliff; % maximum shear strength (Young and Ashford, 2008)
                
                if abs(sigma_max) >= app.tens_strength  % tensile failure criteria
                    failed = 1;
                elseif tau_max >= app.shear_strength % shear failure criteria
                    failed = 1;
                end
                
                
                if failed == 1
                    new_cliff_x = app.x(max_notch_ind);
                    app.cliff_base = floor(new_cliff_x/app.dx) + app.zero_index;
                    app.cliff_top = app.cliff_base + (app.max_z/app.dz);
                    app.x(app.cliff_base:app.cliff_top) = new_cliff_x;
                    app.updatePositionVectors(new_cliff_x);
                end
                
                Hb = 0.39*(9.81^(1/5))*(10*app.Hs^2)^(2/5);
                Hb_depth = Hb/0.7;
                            
                A = Hb*ones(size(app.x));
                for i = (1:1:length(A))
                    A(i) = Hb*exp(-(Hb_depth-(app.sea_level + abs(app.z(i)))))^2;
                    if A(i) > Hb
                        A(i) = Hb;
                    end
                end
                
                app.wl = (A/2) .* sin((2*pi)/(10*sqrt(9.81*Hb_depth)).*(app.x) + (-app.t*pi/16)) + app.sea_level;
                app.wl(app.cliff_base+1:end) = nan;
                
                app.updateProfilePlot(Hb_depth);
                                 
                app.t = app.t + app.dt;
                
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % set buttons
            app.RunButton.Enable = 'on';
            app.InitializeButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            app.stopped = 1;
        end

        % Value changed function: waveHeight
        function waveHeightValueChanged(app, event)
            app.Hs = app.waveHeight.Value;
            
        end

        % Value changed function: seaLevel
        function seaLevelValueChanged(app, event)
            app.sea_level = app.seaLevel.Value;
            
        end

        % Value changed function: precipLevel
        function precipLevelValueChanged(app, event)
            value = app.precipLevel.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.StopButton = uibutton(app.ControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RunButton = uibutton(app.ControlsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.InitializeButton = uibutton(app.ControlsPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.HelpButton = uibutton(app.ControlsPanel, 'push');
            app.HelpButton.Text = 'Help';
            app.SetConditionsPanel = uipanel(app.UIFigure);
            app.SetConditionsPanel.Title = 'Set Conditions';
            app.WaveheightmSpinnerLabel = uilabel(app.SetConditionsPanel);
            app.WaveheightmSpinnerLabel.Text = 'Wave height (m)';
            app.waveHeight = uispinner(app.SetConditionsPanel);
            app.waveHeight.Step = 0.2;
            app.waveHeight.Limits = [0.2 4];
            app.waveHeight.ValueChangedFcn = createCallbackFcn(app, @waveHeightValueChanged, true);
            app.waveHeight.Value = 2;
            app.SealevelmSpinnerLabel = uilabel(app.SetConditionsPanel);
            app.SealevelmSpinnerLabel.Text = 'Sea level (m)';
            app.seaLevel = uispinner(app.SetConditionsPanel);
            app.seaLevel.Step = 0.5;
            app.seaLevel.Limits = [-3 3];
            app.seaLevel.ValueChangedFcn = createCallbackFcn(app, @seaLevelValueChanged, true);
            app.PrecipitationinyrSpinnerLabel = uilabel(app.SetConditionsPanel);
            app.PrecipitationinyrSpinnerLabel.Text = 'Precipitation (in/yr)';
            app.precipLevel = uispinner(app.SetConditionsPanel);
            app.precipLevel.ValueChangedFcn = createCallbackFcn(app, @precipLevelValueChanged, true);
            app.SeaCliffErosionLabel = uilabel(app.UIFigure);
            app.SeaCliffErosionLabel.Text = 'Sea Cliff Erosion';
            app.profileAxes = uiaxes(app.UIFigure);
            app.waterAxes = uiaxes(app.UIFigure);
            app.retreatAxes = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = SeaCliffs

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end